classdef MatrixCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MatrixCalculatorPanel  matlab.ui.container.Panel
        DisplayPanel2          matlab.ui.container.Panel
        centerButton           matlab.ui.control.Button
        DisplayButton          matlab.ui.control.Button
        DisplayPanel1          matlab.ui.container.Panel
        numColField            matlab.ui.control.NumericEditField
        numColFieldLabel       matlab.ui.control.Label
        numRowField            matlab.ui.control.NumericEditField
        numRowFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end
            
            paddingX = 5;
            paddingY = 5;

            numRows = app.numRowField.Value;
            numCols = app.numColField.Value;

            panelWidth = app.DisplayPanel1.Position(3);
            panelHeight = app.DisplayPanel1.Position(4);

            fieldWidth = (panelWidth - (paddingX * (numCols - 1))) / numCols;
            fieldHeight = (panelHeight - (paddingY * (numRows - 1))) / numRows;

            for row = 1:numRows
                for col = 1:numCols
                    field = uieditfield(app.DisplayPanel1,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center');

                    fieldX = (col - 1) * (fieldWidth + paddingX);
                    fieldY = panelHeight - row * (fieldHeight + paddingY);

                    if row == 1
                        fieldY = panelHeight - row * fieldHeight;
                    end
                    
                    field.Tag = sprintf('field_%d_%d', row, col);
                    field.Position = [fieldX, fieldY, fieldWidth, fieldHeight];
                    % pause(2)
                end
            end

            
            children = app.DisplayPanel1.Children;

            for idx = 1:length(children)
                childIdx = children(idx).Tag;
                disp(['The tag of component ' num2str(idx) ' is: ' childIdx]);
            end
            
        end

        % Button pushed function: centerButton
        function centerButtonPushed(app, event)
            containerX = app.MatrixCalculatorPanel.Position(1);
            containerY = app.MatrixCalculatorPanel.Position(2);
            containerWidth = app.MatrixCalculatorPanel.Position(3);
            containerHeight = app.MatrixCalculatorPanel.Position(4);

            centerX = containerX + (containerWidth / 2);
            centerY = containerY + (containerHeight / 2);

            fieldWidth = app.EditField.Position(3);
            fieldHeight = app.EditField.Position(4);

            fieldX = centerX - (fieldWidth / 2);
            fieldY = centerY - (fieldHeight / 2);

            app.EditField.Position = [fieldX, fieldY, fieldWidth, fieldHeight];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MatrixCalculatorPanel = uipanel(app.UIFigure);
            app.MatrixCalculatorPanel.Title = 'Matrix Calculator';
            app.numRowFieldLabel = uilabel(app.MatrixCalculatorPanel);
            app.numRowFieldLabel.Text = 'Number of Rows';
            app.numRowField = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.numRowField.Limits = [0 Inf];
            app.numRowField.AllowEmpty = 'on';
            app.numRowField.Value = [];
            app.numColFieldLabel = uilabel(app.MatrixCalculatorPanel);
            app.numColFieldLabel.Text = 'Number of Columns';
            app.numColField = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.numColField.Limits = [0 Inf];
            app.numColField.AllowEmpty = 'on';
            app.numColField.Value = [];
            app.DisplayPanel1 = uipanel(app.MatrixCalculatorPanel);
            app.DisplayButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.centerButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.centerButton.ButtonPushedFcn = createCallbackFcn(app, @centerButtonPushed, true);
            app.centerButton.Text = 'center';
            app.DisplayPanel2 = uipanel(app.MatrixCalculatorPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end